classdef surgerylog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RolemageLaboratoryUIFigure  matlab.ui.Figure
        NameDropDownLabel           matlab.ui.control.Label
        NameDropDown                matlab.ui.control.DropDown
        MouseIDLabel                matlab.ui.control.Label
        MouseIDEditField            matlab.ui.control.EditField
        DateEditFieldLabel          matlab.ui.control.Label
        DateEditField               matlab.ui.control.EditField
        SurgeryTypeDropDownLabel    matlab.ui.control.Label
        SurgeryTypeDropDown         matlab.ui.control.DropDown
        AnesthesiaDropDownLabel     matlab.ui.control.Label
        AnesthesiaDropDown          matlab.ui.control.DropDown
        StartTimeEditFieldLabel     matlab.ui.control.Label
        StartTimeEditField          matlab.ui.control.EditField
        StopTimeEditFieldLabel      matlab.ui.control.Label
        StopTimeEditField           matlab.ui.control.EditField
        APEditFieldLabel            matlab.ui.control.Label
        APEditField                 matlab.ui.control.NumericEditField
        MLEditFieldLabel            matlab.ui.control.Label
        MLEditField                 matlab.ui.control.NumericEditField
        DVEditFieldLabel            matlab.ui.control.Label
        DVEditField                 matlab.ui.control.NumericEditField
        DescriptionTextAreaLabel    matlab.ui.control.Label
        DescriptionTextArea         matlab.ui.control.TextArea
        BregmaLabel                 matlab.ui.control.Label
        LambdaLabel                 matlab.ui.control.Label
        MLEditField_2Label          matlab.ui.control.Label
        MLEditField_2               matlab.ui.control.NumericEditField
        DVEditField_2Label          matlab.ui.control.Label
        DVEditField_2               matlab.ui.control.NumericEditField
        SurgeryLogLabel             matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        Site1Tab                    matlab.ui.container.Tab
        AreaDropDownLabel           matlab.ui.control.Label
        AreaDropDown_1              matlab.ui.control.DropDown
        MidlineDVEditFieldLabel     matlab.ui.control.Label
        MidlineDVEditField_4        matlab.ui.control.NumericEditField
        VirusEditField_4Label       matlab.ui.control.Label
        VirusEditField_4            matlab.ui.control.EditField
        FiberCheckBox_4             matlab.ui.control.CheckBox
        LensCheckBox_4              matlab.ui.control.CheckBox
        APEditField_3Label          matlab.ui.control.Label
        APEditField_9               matlab.ui.control.NumericEditField
        MLEditField_3Label          matlab.ui.control.Label
        MLEditField_9               matlab.ui.control.NumericEditField
        DVEditField_3Label          matlab.ui.control.Label
        DVEditField_9               matlab.ui.control.NumericEditField
        APEditField_4Label          matlab.ui.control.Label
        APEditField_10              matlab.ui.control.NumericEditField
        MLEditField_4Label          matlab.ui.control.Label
        MLEditField_10              matlab.ui.control.NumericEditField
        DVEditField_4Label          matlab.ui.control.Label
        DVEditField_10              matlab.ui.control.NumericEditField
        RelativeLabel               matlab.ui.control.Label
        AbsoluteLabel               matlab.ui.control.Label
        BilateralSwitch_1           matlab.ui.control.Switch
        LeftRightSwitch_1           matlab.ui.control.Switch
        LandmarkSwitch_1            matlab.ui.control.Switch
        Site2Tab                    matlab.ui.container.Tab
        FiberCheckBox_5             matlab.ui.control.CheckBox
        LensCheckBox_5              matlab.ui.control.CheckBox
        AreaDropDown_5Label         matlab.ui.control.Label
        AreaDropDown_2              matlab.ui.control.DropDown
        MidlineDVEditField_5Label   matlab.ui.control.Label
        MidlineDVEditField_5        matlab.ui.control.NumericEditField
        VirusEditField_5Label       matlab.ui.control.Label
        VirusEditField_5            matlab.ui.control.EditField
        BilateralSwitch_2           matlab.ui.control.Switch
        LeftRightSwitch_2           matlab.ui.control.Switch
        RelativeLabel_8             matlab.ui.control.Label
        AbsoluteLabel_8             matlab.ui.control.Label
        LandmarkSwitch_2            matlab.ui.control.Switch
        APEditField_17Label         matlab.ui.control.Label
        APEditField_11              matlab.ui.control.NumericEditField
        MLEditField_17Label         matlab.ui.control.Label
        MLEditField_11              matlab.ui.control.NumericEditField
        DVEditField_17Label         matlab.ui.control.Label
        DVEditField_11              matlab.ui.control.NumericEditField
        APEditField_18Label         matlab.ui.control.Label
        APEditField_12              matlab.ui.control.NumericEditField
        MLEditField_18Label         matlab.ui.control.Label
        MLEditField_12              matlab.ui.control.NumericEditField
        DVEditField_18Label         matlab.ui.control.Label
        DVEditField_12              matlab.ui.control.NumericEditField
        Site3Tab                    matlab.ui.container.Tab
        FiberCheckBox_6             matlab.ui.control.CheckBox
        LensCheckBox_6              matlab.ui.control.CheckBox
        AreaDropDown_6Label         matlab.ui.control.Label
        AreaDropDown_3              matlab.ui.control.DropDown
        MidlineDVEditField_6Label   matlab.ui.control.Label
        MidlineDVEditField_6        matlab.ui.control.NumericEditField
        VirusEditField_6Label       matlab.ui.control.Label
        VirusEditField_6            matlab.ui.control.EditField
        BilateralSwitch_3           matlab.ui.control.Switch
        LeftRightSwitch_3           matlab.ui.control.Switch
        RelativeLabel_9             matlab.ui.control.Label
        AbsoluteLabel_9             matlab.ui.control.Label
        LandmarkSwitch_3            matlab.ui.control.Switch
        APEditField_17Label_2       matlab.ui.control.Label
        APEditField_13              matlab.ui.control.NumericEditField
        MLEditField_17Label_2       matlab.ui.control.Label
        MLEditField_13              matlab.ui.control.NumericEditField
        DVEditField_17Label_2       matlab.ui.control.Label
        DVEditField_13              matlab.ui.control.NumericEditField
        APEditField_18Label_2       matlab.ui.control.Label
        APEditField_14              matlab.ui.control.NumericEditField
        MLEditField_18Label_2       matlab.ui.control.Label
        MLEditField_14              matlab.ui.control.NumericEditField
        DVEditField_18Label_2       matlab.ui.control.Label
        DVEditField_14              matlab.ui.control.NumericEditField
        Site4Tab                    matlab.ui.container.Tab
        FiberCheckBox_7             matlab.ui.control.CheckBox
        LensCheckBox_7              matlab.ui.control.CheckBox
        AreaDropDown_7Label         matlab.ui.control.Label
        AreaDropDown_4              matlab.ui.control.DropDown
        MidlineDVEditField_7Label   matlab.ui.control.Label
        MidlineDVEditField_7        matlab.ui.control.NumericEditField
        VirusEditField_7Label       matlab.ui.control.Label
        VirusEditField_7            matlab.ui.control.EditField
        BilateralSwitch_4           matlab.ui.control.Switch
        LeftRightSwitch_4           matlab.ui.control.Switch
        RelativeLabel_10            matlab.ui.control.Label
        AbsoluteLabel_10            matlab.ui.control.Label
        LandmarkSwitch_4            matlab.ui.control.Switch
        APEditField_15Label         matlab.ui.control.Label
        APEditField_15              matlab.ui.control.NumericEditField
        MLEditField_15Label         matlab.ui.control.Label
        MLEditField_15              matlab.ui.control.NumericEditField
        DVEditField_15Label         matlab.ui.control.Label
        DVEditField_15              matlab.ui.control.NumericEditField
        APEditField_16Label         matlab.ui.control.Label
        APEditField_16              matlab.ui.control.NumericEditField
        MLEditField_16Label         matlab.ui.control.Label
        MLEditField_16              matlab.ui.control.NumericEditField
        DVEditField_16Label         matlab.ui.control.Label
        DVEditField_16              matlab.ui.control.NumericEditField
        SaveLogButton               matlab.ui.control.Button
        SexDropDown                 matlab.ui.control.DropDown
        DOBEditFieldLabel           matlab.ui.control.Label
        DOBEditField                matlab.ui.control.EditField
        gEditFieldLabel             matlab.ui.control.Label
        WeightEditField             matlab.ui.control.NumericEditField
        AddNotesButton              matlab.ui.control.Button
        CalButton                   matlab.ui.control.Button
        LookupButton                matlab.ui.control.Button
        KetorolacLabel              matlab.ui.control.Label
        KetorolacEditField          matlab.ui.control.NumericEditField
        mL01mL20gLabel              matlab.ui.control.Label
        APEditField_2Label          matlab.ui.control.Label
        APEditField_2               matlab.ui.control.NumericEditField
        CreatePDFButton             matlab.ui.control.Button
    end


    properties (Access = private)
        SelectedFile % Name of file selected in getlogfilename app
                     % to be used to fill in old information
        SaveFilesFolder % Folder where data files are to be saved
        BrainInfo % Information on brain areas and their coordinate relative to bregma and lambda
    end

    methods (Access = private)
    
        function updatesites(app)
          
            bregmaAP = app.APEditField.Value;
            bregmaML = app.MLEditField.Value;
            lambdaAP = app.APEditField_2.Value;
            lambdaML = app.MLEditField_2.Value;
            
            % For Tab 1, the index for the relative coordinates is 9 and for the absolute
            % coordinates is 10. For Tab 2, the numbers are 11 and 12. For Tab 3, the nubmers
            % are 13 and 14. For Tab 4, the numbers are 15 and 16. The corresponding
            % numbers for the four midline DV are 4, 5, 6, and 7. I should rename them
            % to something sensible ... maybe another year.
            relativeIdx = [9,11,13,15];
            absoluteIdx = relativeIdx + 1;
            midlineIdx = [4, 5, 6, 7];
            for iTabs = 1:4
                if strcmp(app.(['LandmarkSwitch_',num2str(iTabs)]).Value,'Bregma')
                    referenceAP = bregmaAP;
                    referenceML = bregmaML;
                else
                    referenceAP = lambdaAP;
                    referenceML = lambdaML;
                end
                APName1 = ['APEditField_',num2str(relativeIdx(iTabs))];
                APName2 = ['APEditField_',num2str(absoluteIdx(iTabs))];
                MLName1 = ['MLEditField_',num2str(relativeIdx(iTabs))];
                MLName2 = ['MLEditField_',num2str(absoluteIdx(iTabs))];
                DVName1 = ['DVEditField_',num2str(relativeIdx(iTabs))];
                DVName2 = ['DVEditField_',num2str(absoluteIdx(iTabs))];
                MidlineName = ['MidlineDVEditField_',num2str(midlineIdx(iTabs))];
                app.(APName2).Value = referenceAP - app.(APName1).Value;
                app.(MLName2).Value = referenceML - app.(MLName1).Value;
                app.(DVName2).Value = app.(MidlineName).Value - app.(DVName1).Value;
            end
       
        end
        
        function populateGUIwithsavedvalues(app)
            selectedFile = app.SelectedFile;
            if (numel(selectedFile)<10) || ~strcmp(selectedFile(end-2:end),'mat')
                return
            end
            load([app.SaveFilesFolder,'\',selectedFile],'out')
            name = out.name;
            if ~ismember(app.NameDropDown.Items,name)
                app.NameDropDown.Items{end+1} = name;
            end
            app.NameDropDown.Value = name;
            app.DateEditField.Value = out.surgeryDate;
            app.MouseIDEditField.Value = out.mouseID;
            app.WeightEditField.Value = out.mouseWeight;
            app.SexDropDown.Value = out.mouseSex;
            app.DOBEditField.Value = out.mouseDOB;
            app.StartTimeEditField.Value = out.surgeryStartTime;
            app.StopTimeEditField.Value = out.surgeryStopTime;
            app.AnesthesiaDropDown.Value = out.anesthesia;
            app.SurgeryTypeDropDown.Value = out.surgeryType;
            app.KetorolacEditField.Value = out.ketorolac;
            app.DescriptionTextArea.Value = out.description;
            app.APEditField.Value = out.bregmaPosition(1);
            app.MLEditField.Value = out.bregmaPosition(2);
            app.DVEditField.Value = out.bregmaPosition(3);
            app.APEditField_2.Value = out.lambdaPosition(1);
            app.MLEditField_2.Value = out.lambdaPosition(2);
            app.DVEditField_2.Value = out.lambdaPosition(3);
  
            % Enter values into the site tabs
            % Yes, I know I should fix the naming/indexing problems
            for iTabs = 1:4
                app.(['AreaDropDown_',num2str(iTabs)]).Value = out.site(iTabs).Area;
                app.(['BilateralSwitch_',num2str(iTabs)]).Value = out.site(iTabs).bilateral;
            end
            for ii = 4:7
                app.(['MidlineDVEditField_',num2str(ii)]).Value = out.site(ii-3).midlineDV;
                app.(['VirusEditField_',num2str(ii)]).Value = string(out.site(ii-3).virus);
                app.(['FiberCheckBox_',num2str(ii)]).Value = out.site(ii-3).fiber;
                app.(['LensCheckBox_',num2str(ii)]).Value = out.site(ii-3).lens;
            end
            kk = 0;
            for jj = 9:2:15
                kk = kk+1;
                app.(['APEditField_',num2str(jj)]).Value = out.site(kk).relativeAP;
                app.(['APEditField_',num2str(jj+1)]).Value = out.site(kk).absoluteAP;
                app.(['MLEditField_',num2str(jj)]).Value = out.site(kk).relativeML;
                app.(['MLEditField_',num2str(jj+1)]).Value = out.site(kk).absoluteML;
                app.(['DVEditField_',num2str(jj)]).Value = out.site(kk).relativeDV;
                app.(['DVEditField_',num2str(jj+1)]).Value = out.site(kk).absoluteDV;
            end
            
        end
        
        function updatebraininfo(app,loadSavedInfo)
            
            if loadSavedInfo   %  load from brainInfo.mat
                info = load([app.SaveFilesFolder,'\brainInfo.mat']);
                info.BrainArea = [{'None'},info.BrainArea,{'Add / Edit'}];
                app.BrainInfo = info;
            end
            info = app.BrainInfo;
            relativeIdx = [9,11,13,15]; % I really should fix the name indices
            for iTabs = 1:4    
                areas = app.(['AreaDropDown_',num2str(iTabs)]).Items;
                value = app.(['AreaDropDown_',num2str(iTabs)]).Value;
                idx = find(contains(areas,value));
                app.(['AreaDropDown_',num2str(iTabs)]).Items = info.BrainArea;       
                if idx==1   % None
                    app.(['APEditField_',num2str(relativeIdx(iTabs))]).Value = 0;
                    app.(['MLEditField_',num2str(relativeIdx(iTabs))]).Value = 0;
                    app.(['DVEditField_',num2str(relativeIdx(iTabs))]).Value = 0;
                elseif idx==numel(areas)
                    if numel(areas)==numel(info.BrainArea)
                        idx = 2;
                    end
                    app.(['AreaDropDown_',num2str(iTabs)]).Value = info.BrainArea(idx);
                end
                ref = app.(['LandmarkSwitch_',num2str(iTabs)]).Value;
                if idx > 1
                    if strcmp(ref,'Bregma')
                        app.(['APEditField_',num2str(relativeIdx(iTabs))]).Value = ...
                            info.RelativeToBregma(idx-1,1);
                        app.(['MLEditField_',num2str(relativeIdx(iTabs))]).Value = ...
                            info.RelativeToBregma(idx-1,2);
                    else
                        app.(['APEditField_',num2str(relativeIdx(iTabs))]).Value = ...
                            info.RelativeToLambda(idx-1,1);
                        app.(['MLEditField_',num2str(relativeIdx(iTabs))]).Value = ...
                            info.RelativeToLambda(idx-1,2);
                    end
                    app.(['DVEditField_',num2str(relativeIdx(iTabs))]).Value = ...
                        info.RelativeToMidlineDV(idx-1);
                end
            end
            updatesites(app)  
        end
        
    end

    methods (Access = public)
    
        function setlogfilename(app,selectedFile)
                 app.SelectedFile = selectedFile;
                 populateGUIwithsavedvalues(app)
        end
        
        function checkbraininfo(app)
            
            if exist([app.SaveFilesFolder,'\brainInfo.mat'],'file')
                loadSavedInfo = true;
            else
                loadSavedInfo = false;
            end
            updatebraininfo(app,loadSavedInfo)
             
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DateEditField.Value = datestr(now,2);
            if isdeployed
                foo = winqueryreg('HKEY_CURRENT_USER',...
                    ['Software\Microsoft\Windows\CurrentVersion\',...
                    'Explorer\Shell Folders'],'Personal');
                saveFilesFolder = [foo,'\SurgeryLogData'];
            else
                saveFilesFolder = [userpath,'\SurgeryLog\SurgeryLogData'];
            end
            if ~exist(saveFilesFolder,'dir')
                mkdir(saveFilesFolder)
            end
            if exist([saveFilesFolder,'\namesOfSurgeons.mat'],'file')
                boo = load([saveFilesFolder,'\namesOfSurgeons.mat']);
                app.NameDropDown.Items = boo.names;
            end
            app.SaveFilesFolder = saveFilesFolder;
            if exist([saveFilesFolder,'\brainInfo.mat'],'file')
                loadSavedInfo = true;
                updatebraininfo(app,loadSavedInfo)
            else
                app.BrainInfo.BrainArea = {'None','NBM','BLA','vHIPP','PFC','Add / Edit'};
                app.BrainInfo.RelativeToBregma = zeros(numel(app.BrainInfo.BrainArea),2);
                app.BrainInfo.RelativeToLambda = zeros(numel(app.BrainInfo.BrainArea),2);
                app.BrainInfo.RelativeToMidlineDV = zeros(numel(app.BrainInfo.BrainArea),1);
            end      

        end

        % Value changed function: NameDropDown
        function NameDropDownValueChanged(app, event)
            if strcmp(app.NameDropDown.Value,'Add / Delete')
                names = namesdialog(app.NameDropDown.Items);
                app.NameDropDown.Items = names;
                app.NameDropDown.Value = names(1);
                save([app.SaveFilesFolder,'\namesOfSurgeons.mat'],'names');
            end
        end

        % Button pushed function: CalButton
        function CalButtonPushed(app, event)
            T = datestr(uigetdate(now),2);
            if ~isempty(T)
                app.DOBEditField.Value = T;
            end
        end

        % Button pushed function: AddNotesButton
        function AddNotesButtonPushed(app, event)
            mouseID = app.MouseIDEditField.Value;
            surgeryDate = app.DateEditField.Value;
            name = app.NameDropDown.Value;
            if contains(mouseID,'enter') || isempty(strtrim(mouseID))
                warndlg('Enter a mouse ID first.')
                return
            end
            formattedDate = [datestr(surgeryDate,5),datestr(surgeryDate,7),datestr(surgeryDate,11)];
            commaPosition = strfind(name,',');
            lastName = strtrim(name(1:commaPosition-1));
            firstName = strtrim(name(commaPosition+1:end));
            if isempty(mouseID), mouseID = 'Unknown'; end
            fileName = [app.SaveFilesFolder,'\Notes_Mouse_',mouseID,'_Date_',formattedDate,'_Name_',firstName,'_',lastName,'.txt'];
            addsurgerynotes(fileName);
        end

        % Value changed function: APEditField, APEditField_11, 
        % APEditField_13, APEditField_15, APEditField_2, APEditField_9, 
        % DVEditField, DVEditField_11, DVEditField_13, DVEditField_15, 
        % DVEditField_2, DVEditField_9, MLEditField, MLEditField_11, 
        % MLEditField_13, MLEditField_15, MLEditField_2, MLEditField_9, 
        % MidlineDVEditField_4, MidlineDVEditField_5, 
        % MidlineDVEditField_6, MidlineDVEditField_7
        function APEditFieldValueChanged(app, event)
            updatesites(app)
        end

        % Value changed function: LeftRightSwitch_1
        function LeftRightSwitch_1ValueChanged(app, event)
            app.MLEditField_9.Value = -app.MLEditField_9.Value;
            updatesites(app)
        end

        % Value changed function: LeftRightSwitch_2
        function LeftRightSwitch_2ValueChanged(app, event)
            app.MLEditField_11.Value = -app.MLEditField_11.Value;
            updatesites(app)
        end

        % Value changed function: LeftRightSwitch_3
        function LeftRightSwitch_3ValueChanged(app, event)
            app.MLEditField_13.Value = -app.MLEditField_13.Value;
            updatesites(app)
        end

        % Value changed function: LeftRightSwitch_4
        function LeftRightSwitch_4ValueChanged(app, event)
            app.MLEditField_15.Value = -app.MLEditField_15.Value;
            updatesites(app)
        end

        % Button pushed function: SaveLogButton
        function SaveLogButtonPushed(app, event)
            
            % Collect data from GUI
            
            out.name = app.NameDropDown.Value;
            out.surgeryDate = app.DateEditField.Value;
            out.mouseID = app.MouseIDEditField.Value;
            out.mouseWeight = app.WeightEditField.Value;
            out.mouseSex = app.SexDropDown.Value;
            out.mouseDOB = app.DOBEditField.Value;
            out.surgeryStartTime = app.StartTimeEditField.Value;
            out.surgeryStopTime = app.StopTimeEditField.Value;
            out.anesthesia = app.AnesthesiaDropDown.Value;
            out.surgeryType = app.SurgeryTypeDropDown.Value;
            out.ketorolac = app.KetorolacEditField.Value;
            out.description = app.DescriptionTextArea.Value;
            out.bregmaPosition = [app.APEditField.Value, app.MLEditField.Value, app.DVEditField.Value];
            out.lambdaPosition = [app.APEditField_2.Value, app.MLEditField_2.Value, app.DVEditField_2.Value];
            relativeIdx = [9,11,13,15];
            absoluteIdx = relativeIdx + 1;
            midlineIdx = [4, 5, 6, 7];
            for iTabs = 1:4
                out.site(iTabs).Area = app.(['AreaDropDown_',num2str(iTabs)]).Value;
                if strcmp(out.site(iTabs),'None')
                    continue
                end
                APName1 = ['APEditField_',num2str(relativeIdx(iTabs))];
                APName2 = ['APEditField_',num2str(absoluteIdx(iTabs))];
                MLName1 = ['MLEditField_',num2str(relativeIdx(iTabs))];
                MLName2 = ['MLEditField_',num2str(absoluteIdx(iTabs))];
                DVName1 = ['DVEditField_',num2str(relativeIdx(iTabs))];
                DVName2 = ['DVEditField_',num2str(absoluteIdx(iTabs))];
                MidlineName = ['MidlineDVEditField_',num2str(midlineIdx(iTabs))];
                out.site(iTabs).midlineDV = app.(MidlineName).Value;
                out.site(iTabs).relativeAP = app.(APName1).Value;
                out.site(iTabs).absoluteAP = app.(APName2).Value;
                out.site(iTabs).relativeML = app.(MLName1).Value;
                out.site(iTabs).absoluteML = app.(MLName2).Value;
                out.site(iTabs).relativeDV = app.(DVName1).Value;
                out.site(iTabs).absoluteDV = app.(DVName2).Value;
                out.site(iTabs).virus = app.(['VirusEditField_',num2str(midlineIdx(iTabs))]).Value;
                if contains(out.site(iTabs).virus,'name')
                    out.site(iTabs).virus = [];
                end
                out.site(iTabs).fiber = app.(['FiberCheckBox_',num2str(midlineIdx(iTabs))]).Value;
                out.site(iTabs).lens = app.(['LensCheckBox_',num2str(midlineIdx(iTabs))]).Value;
                out.site(iTabs).bilateral = app.(['BilateralSwitch_',num2str(iTabs)]).Value;
                out.site(iTabs).leftright = app.(['LeftRightSwitch_',num2str(iTabs)]).Value;
            end
            
            
            % Save MAT file and PDF file (local and Google Drive)
            formattedDate = [datestr(out.surgeryDate,5),datestr(out.surgeryDate,7),datestr(out.surgeryDate,11)];
            commaPosition = strfind(out.name,',');
            lastName = strtrim(out.name(1:commaPosition-1));
            firstName = strtrim(out.name(commaPosition+1:end));
            if isempty(out.mouseID) || contains(out.mouseID,'enter')
                warndlg('Enter a mouse ID before saving.');
                return
            end
            logfileName = ['SurgeryLog_Mouse_',out.mouseID,'_Date_',formattedDate,'_Name_',firstName,'_',lastName,'.mat'];
            save([app.SaveFilesFolder,'\',logfileName],'out');
        end

        % Button pushed function: LookupButton
        function LookupButtonPushed(app, event)
            getlogfilename(app,app.SaveFilesFolder);
        end

        % Value changed function: MouseIDEditField
        function MouseIDEditFieldValueChanged(app, event)
            value = app.MouseIDEditField.Value;
            value(isspace(value)) = [];
            app.MouseIDEditField.Value = value;
        end

        % Value changed function: WeightEditField
        function WeightEditFieldValueChanged(app, event)
            value = app.WeightEditField.Value;
            ketorolac = value * (0.1/20); % volume in mL if dosage is 0.1 mL per 20 g mouse
            app.KetorolacEditField.Value = ketorolac;
        end

        % Value changed function: AreaDropDown_1
        function AreaDropDown_1ValueChanged(app, event)
            value = app.AreaDropDown_1.Value;
            if strncmp(value, 'Add', 3)
                getbraininfo(app,app.SaveFilesFolder)
            else
                updatebraininfo(app,false)
                updatesites(app)
            end
        end

        % Value changed function: AreaDropDown_2
        function AreaDropDown_2ValueChanged(app, event)
            value = app.AreaDropDown_2.Value;
            if strncmp(value, 'Add', 3)
                getbraininfo(app,app.SaveFilesFolder)
            else
                updatebraininfo(app,false)
                updatesites(app)
            end
        end

        % Value changed function: AreaDropDown_3
        function AreaDropDown_3ValueChanged(app, event)
            value = app.AreaDropDown_3.Value;
            if strncmp(value, 'Add', 3)
                getbraininfo(app,app.SaveFilesFolder)
            else
                updatebraininfo(app,false)
                updatesites(app)
            end
        end

        % Value changed function: AreaDropDown_4
        function AreaDropDown_4ValueChanged(app, event)
            value = app.AreaDropDown_4.Value;
            if strncmp(value, 'Add', 3)
                getbraininfo(app,app.SaveFilesFolder)
            else
                updatebraininfo(app,false)
                updatesites(app)
            end
        end

        % Value changed function: LandmarkSwitch_1, LandmarkSwitch_2, 
        % LandmarkSwitch_3, LandmarkSwitch_4
        function LandmarkSwitch_1ValueChanged(app, event)
            updatebraininfo(app,false)
            updatesites(app)            
        end

        % Close request function: RolemageLaboratoryUIFigure
        function RolemageLaboratoryUIFigureCloseRequest(app, event)
            
            delete(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RolemageLaboratoryUIFigure = uifigure;
            app.RolemageLaboratoryUIFigure.Name = 'Rolemage Laboratory';
            app.RolemageLaboratoryUIFigure.CloseRequestFcn = createCallbackFcn(app, @RolemageLaboratoryUIFigureCloseRequest, true);
            app.NameDropDownLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.NameDropDownLabel.Text = 'Name';
            app.NameDropDown = uidropdown(app.RolemageLaboratoryUIFigure);
            app.NameDropDown.Items = {'Ananth, Mala', 'Desai, Niraj', 'Jone, Alice', 'Parsey, Darian', 'Rajebhosale, Prithviraj ', 'Wilking, Jennifer', 'Zhong, Chongbo', 'Add / Delete'};
            app.NameDropDown.ValueChangedFcn = createCallbackFcn(app, @NameDropDownValueChanged, true);
            app.NameDropDown.Value = 'Ananth, Mala';
            app.MouseIDLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.MouseIDLabel.Text = 'Mouse ID';
            app.MouseIDEditField = uieditfield(app.RolemageLaboratoryUIFigure, 'text');
            app.MouseIDEditField.ValueChangedFcn = createCallbackFcn(app, @MouseIDEditFieldValueChanged, true);
            app.MouseIDEditField.Value = 'enter ID here';
            app.DateEditFieldLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.DateEditFieldLabel.Text = 'Date';
            app.DateEditField = uieditfield(app.RolemageLaboratoryUIFigure, 'text');
            app.DateEditField.Editable = 'off';
            app.DateEditField.Value = 'today''s date';
            app.SurgeryTypeDropDownLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.SurgeryTypeDropDownLabel.Text = 'Surgery Type';
            app.SurgeryTypeDropDown = uidropdown(app.RolemageLaboratoryUIFigure);
            app.SurgeryTypeDropDown.Items = {'Virus only', 'Light fiber', 'GRIN lens', 'GRIN baseplate'};
            app.SurgeryTypeDropDown.Value = 'Virus only';
            app.AnesthesiaDropDownLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.AnesthesiaDropDownLabel.Text = 'Anesthesia';
            app.AnesthesiaDropDown = uidropdown(app.RolemageLaboratoryUIFigure);
            app.AnesthesiaDropDown.Items = {'Isoflurane', 'Ketamine'};
            app.AnesthesiaDropDown.Value = 'Isoflurane';
            app.StartTimeEditFieldLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.StartTimeEditFieldLabel.Text = 'Start Time';
            app.StartTimeEditField = uieditfield(app.RolemageLaboratoryUIFigure, 'text');
            app.StartTimeEditField.Value = '11:00';
            app.StopTimeEditFieldLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.StopTimeEditFieldLabel.Text = 'Stop Time';
            app.StopTimeEditField = uieditfield(app.RolemageLaboratoryUIFigure, 'text');
            app.StopTimeEditField.Value = '13:00';
            app.APEditFieldLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.APEditFieldLabel.Text = 'AP';
            app.APEditField = uieditfield(app.RolemageLaboratoryUIFigure, 'numeric');
            app.APEditField.Limits = [0 10];
            app.APEditField.ValueDisplayFormat = '%1.4f';
            app.APEditField.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.MLEditFieldLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.MLEditFieldLabel.Text = 'ML';
            app.MLEditField = uieditfield(app.RolemageLaboratoryUIFigure, 'numeric');
            app.MLEditField.Limits = [0 10];
            app.MLEditField.ValueDisplayFormat = '%1.4f';
            app.MLEditField.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.DVEditFieldLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.DVEditFieldLabel.Text = 'DV';
            app.DVEditField = uieditfield(app.RolemageLaboratoryUIFigure, 'numeric');
            app.DVEditField.Limits = [0 10];
            app.DVEditField.ValueDisplayFormat = '%1.4f';
            app.DVEditField.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.DescriptionTextAreaLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.RolemageLaboratoryUIFigure);
            app.BregmaLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.BregmaLabel.Text = 'Bregma';
            app.LambdaLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.LambdaLabel.Text = 'Lambda';
            app.MLEditField_2Label = uilabel(app.RolemageLaboratoryUIFigure);
            app.MLEditField_2Label.Text = 'ML';
            app.MLEditField_2 = uieditfield(app.RolemageLaboratoryUIFigure, 'numeric');
            app.MLEditField_2.Limits = [0 10];
            app.MLEditField_2.ValueDisplayFormat = '%1.4f';
            app.MLEditField_2.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.DVEditField_2Label = uilabel(app.RolemageLaboratoryUIFigure);
            app.DVEditField_2Label.Text = 'DV';
            app.DVEditField_2 = uieditfield(app.RolemageLaboratoryUIFigure, 'numeric');
            app.DVEditField_2.Limits = [0 10];
            app.DVEditField_2.ValueDisplayFormat = '%1.4f';
            app.DVEditField_2.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.SurgeryLogLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.SurgeryLogLabel.Text = 'Surgery Log';
            app.TabGroup = uitabgroup(app.RolemageLaboratoryUIFigure);
            app.Site1Tab = uitab(app.TabGroup);
            app.Site1Tab.Title = 'Site 1';
            app.AreaDropDownLabel = uilabel(app.Site1Tab);
            app.AreaDropDownLabel.Text = 'Area';
            app.AreaDropDown_1 = uidropdown(app.Site1Tab);
            app.AreaDropDown_1.Items = {'None', 'NBM', 'BLA', 'vHIPP', 'PFC', 'Add / Edit'};
            app.AreaDropDown_1.ValueChangedFcn = createCallbackFcn(app, @AreaDropDown_1ValueChanged, true);
            app.AreaDropDown_1.Value = 'None';
            app.MidlineDVEditFieldLabel = uilabel(app.Site1Tab);
            app.MidlineDVEditFieldLabel.Text = 'Midline DV';
            app.MidlineDVEditField_4 = uieditfield(app.Site1Tab, 'numeric');
            app.MidlineDVEditField_4.Limits = [0 10];
            app.MidlineDVEditField_4.ValueDisplayFormat = '%1.4f';
            app.MidlineDVEditField_4.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.VirusEditField_4Label = uilabel(app.Site1Tab);
            app.VirusEditField_4Label.Text = 'Virus';
            app.VirusEditField_4 = uieditfield(app.Site1Tab, 'text');
            app.VirusEditField_4.Value = 'name & conc';
            app.FiberCheckBox_4 = uicheckbox(app.Site1Tab);
            app.FiberCheckBox_4.Text = 'Fiber';
            app.LensCheckBox_4 = uicheckbox(app.Site1Tab);
            app.LensCheckBox_4.Text = 'Lens';
            app.APEditField_3Label = uilabel(app.Site1Tab);
            app.APEditField_3Label.Text = 'AP';
            app.APEditField_9 = uieditfield(app.Site1Tab, 'numeric');
            app.APEditField_9.ValueDisplayFormat = '%1.4f';
            app.APEditField_9.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.MLEditField_3Label = uilabel(app.Site1Tab);
            app.MLEditField_3Label.Text = 'ML';
            app.MLEditField_9 = uieditfield(app.Site1Tab, 'numeric');
            app.MLEditField_9.ValueDisplayFormat = '%1.4f';
            app.MLEditField_9.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.DVEditField_3Label = uilabel(app.Site1Tab);
            app.DVEditField_3Label.Text = 'DV';
            app.DVEditField_9 = uieditfield(app.Site1Tab, 'numeric');
            app.DVEditField_9.ValueDisplayFormat = '%1.4f';
            app.DVEditField_9.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.APEditField_4Label = uilabel(app.Site1Tab);
            app.APEditField_4Label.Text = 'AP';
            app.APEditField_10 = uieditfield(app.Site1Tab, 'numeric');
            app.APEditField_10.ValueDisplayFormat = '%1.4f';
            app.APEditField_10.Editable = 'off';
            app.MLEditField_4Label = uilabel(app.Site1Tab);
            app.MLEditField_4Label.Text = 'ML';
            app.MLEditField_10 = uieditfield(app.Site1Tab, 'numeric');
            app.MLEditField_10.ValueDisplayFormat = '%1.4f';
            app.MLEditField_10.Editable = 'off';
            app.DVEditField_4Label = uilabel(app.Site1Tab);
            app.DVEditField_4Label.Text = 'DV';
            app.DVEditField_10 = uieditfield(app.Site1Tab, 'numeric');
            app.DVEditField_10.ValueDisplayFormat = '%1.4f';
            app.DVEditField_10.Editable = 'off';
            app.RelativeLabel = uilabel(app.Site1Tab);
            app.RelativeLabel.Text = 'Relative';
            app.AbsoluteLabel = uilabel(app.Site1Tab);
            app.AbsoluteLabel.Text = 'Absolute';
            app.BilateralSwitch_1 = uiswitch(app.Site1Tab, 'slider');
            app.BilateralSwitch_1.Items = {'Bilateral', 'Unilateral'};
            app.BilateralSwitch_1.Value = 'Bilateral';
            app.LeftRightSwitch_1 = uiswitch(app.Site1Tab, 'slider');
            app.LeftRightSwitch_1.Items = {'Right', 'Left'};
            app.LeftRightSwitch_1.ValueChangedFcn = createCallbackFcn(app, @LeftRightSwitch_1ValueChanged, true);
            app.LeftRightSwitch_1.Value = 'Right';
            app.LandmarkSwitch_1 = uiswitch(app.Site1Tab, 'slider');
            app.LandmarkSwitch_1.Items = {'Bregma', 'Lambda'};
            app.LandmarkSwitch_1.ValueChangedFcn = createCallbackFcn(app, @LandmarkSwitch_1ValueChanged, true);
            app.LandmarkSwitch_1.Value = 'Bregma';
            app.Site2Tab = uitab(app.TabGroup);
            app.Site2Tab.Title = 'Site 2';
            app.FiberCheckBox_5 = uicheckbox(app.Site2Tab);
            app.FiberCheckBox_5.Text = 'Fiber';
            app.LensCheckBox_5 = uicheckbox(app.Site2Tab);
            app.LensCheckBox_5.Text = 'Lens';
            app.AreaDropDown_5Label = uilabel(app.Site2Tab);
            app.AreaDropDown_5Label.Text = 'Area';
            app.AreaDropDown_2 = uidropdown(app.Site2Tab);
            app.AreaDropDown_2.Items = {'None', 'NBM', 'BLA', 'vHIPP', 'PFC', 'Add / Edit'};
            app.AreaDropDown_2.ValueChangedFcn = createCallbackFcn(app, @AreaDropDown_2ValueChanged, true);
            app.AreaDropDown_2.Value = 'None';
            app.MidlineDVEditField_5Label = uilabel(app.Site2Tab);
            app.MidlineDVEditField_5Label.Text = 'Midline DV';
            app.MidlineDVEditField_5 = uieditfield(app.Site2Tab, 'numeric');
            app.MidlineDVEditField_5.Limits = [0 10];
            app.MidlineDVEditField_5.ValueDisplayFormat = '%1.4f';
            app.MidlineDVEditField_5.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.VirusEditField_5Label = uilabel(app.Site2Tab);
            app.VirusEditField_5Label.Text = 'Virus';
            app.VirusEditField_5 = uieditfield(app.Site2Tab, 'text');
            app.VirusEditField_5.Value = 'name & conc';
            app.BilateralSwitch_2 = uiswitch(app.Site2Tab, 'slider');
            app.BilateralSwitch_2.Items = {'Bilateral', 'Unilateral'};
            app.BilateralSwitch_2.Value = 'Bilateral';
            app.LeftRightSwitch_2 = uiswitch(app.Site2Tab, 'slider');
            app.LeftRightSwitch_2.Items = {'Right', 'Left'};
            app.LeftRightSwitch_2.ValueChangedFcn = createCallbackFcn(app, @LeftRightSwitch_2ValueChanged, true);
            app.LeftRightSwitch_2.Value = 'Right';
            app.RelativeLabel_8 = uilabel(app.Site2Tab);
            app.RelativeLabel_8.Text = 'Relative';
            app.AbsoluteLabel_8 = uilabel(app.Site2Tab);
            app.AbsoluteLabel_8.Text = 'Absolute';
            app.LandmarkSwitch_2 = uiswitch(app.Site2Tab, 'slider');
            app.LandmarkSwitch_2.Items = {'Bregma', 'Lambda'};
            app.LandmarkSwitch_2.ValueChangedFcn = createCallbackFcn(app, @LandmarkSwitch_1ValueChanged, true);
            app.LandmarkSwitch_2.Value = 'Bregma';
            app.APEditField_17Label = uilabel(app.Site2Tab);
            app.APEditField_17Label.Text = 'AP';
            app.APEditField_11 = uieditfield(app.Site2Tab, 'numeric');
            app.APEditField_11.ValueDisplayFormat = '%1.4f';
            app.APEditField_11.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.MLEditField_17Label = uilabel(app.Site2Tab);
            app.MLEditField_17Label.Text = 'ML';
            app.MLEditField_11 = uieditfield(app.Site2Tab, 'numeric');
            app.MLEditField_11.ValueDisplayFormat = '%1.4f';
            app.MLEditField_11.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.DVEditField_17Label = uilabel(app.Site2Tab);
            app.DVEditField_17Label.Text = 'DV';
            app.DVEditField_11 = uieditfield(app.Site2Tab, 'numeric');
            app.DVEditField_11.ValueDisplayFormat = '%1.4f';
            app.DVEditField_11.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.APEditField_18Label = uilabel(app.Site2Tab);
            app.APEditField_18Label.Text = 'AP';
            app.APEditField_12 = uieditfield(app.Site2Tab, 'numeric');
            app.APEditField_12.ValueDisplayFormat = '%1.4f';
            app.APEditField_12.Editable = 'off';
            app.MLEditField_18Label = uilabel(app.Site2Tab);
            app.MLEditField_18Label.Text = 'ML';
            app.MLEditField_12 = uieditfield(app.Site2Tab, 'numeric');
            app.MLEditField_12.ValueDisplayFormat = '%1.4f';
            app.MLEditField_12.Editable = 'off';
            app.DVEditField_18Label = uilabel(app.Site2Tab);
            app.DVEditField_18Label.Text = 'DV';
            app.DVEditField_12 = uieditfield(app.Site2Tab, 'numeric');
            app.DVEditField_12.ValueDisplayFormat = '%1.4f';
            app.DVEditField_12.Editable = 'off';
            app.Site3Tab = uitab(app.TabGroup);
            app.Site3Tab.Title = 'Site 3';
            app.FiberCheckBox_6 = uicheckbox(app.Site3Tab);
            app.FiberCheckBox_6.Text = 'Fiber';
            app.LensCheckBox_6 = uicheckbox(app.Site3Tab);
            app.LensCheckBox_6.Text = 'Lens';
            app.AreaDropDown_6Label = uilabel(app.Site3Tab);
            app.AreaDropDown_6Label.Text = 'Area';
            app.AreaDropDown_3 = uidropdown(app.Site3Tab);
            app.AreaDropDown_3.Items = {'None', 'NBM', 'BLA', 'vHIPP', 'PFC', 'Add / Edit'};
            app.AreaDropDown_3.ValueChangedFcn = createCallbackFcn(app, @AreaDropDown_3ValueChanged, true);
            app.AreaDropDown_3.Value = 'None';
            app.MidlineDVEditField_6Label = uilabel(app.Site3Tab);
            app.MidlineDVEditField_6Label.Text = 'Midline DV';
            app.MidlineDVEditField_6 = uieditfield(app.Site3Tab, 'numeric');
            app.MidlineDVEditField_6.Limits = [0 10];
            app.MidlineDVEditField_6.ValueDisplayFormat = '%1.4f';
            app.MidlineDVEditField_6.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.VirusEditField_6Label = uilabel(app.Site3Tab);
            app.VirusEditField_6Label.Text = 'Virus';
            app.VirusEditField_6 = uieditfield(app.Site3Tab, 'text');
            app.VirusEditField_6.Value = 'name & conc';
            app.BilateralSwitch_3 = uiswitch(app.Site3Tab, 'slider');
            app.BilateralSwitch_3.Items = {'Bilateral', 'Unilateral'};
            app.BilateralSwitch_3.Value = 'Bilateral';
            app.LeftRightSwitch_3 = uiswitch(app.Site3Tab, 'slider');
            app.LeftRightSwitch_3.Items = {'Right', 'Left'};
            app.LeftRightSwitch_3.ValueChangedFcn = createCallbackFcn(app, @LeftRightSwitch_3ValueChanged, true);
            app.LeftRightSwitch_3.Value = 'Right';
            app.RelativeLabel_9 = uilabel(app.Site3Tab);
            app.RelativeLabel_9.Text = 'Relative';
            app.AbsoluteLabel_9 = uilabel(app.Site3Tab);
            app.AbsoluteLabel_9.Text = 'Absolute';
            app.LandmarkSwitch_3 = uiswitch(app.Site3Tab, 'slider');
            app.LandmarkSwitch_3.Items = {'Bregma', 'Lambda'};
            app.LandmarkSwitch_3.ValueChangedFcn = createCallbackFcn(app, @LandmarkSwitch_1ValueChanged, true);
            app.LandmarkSwitch_3.Value = 'Bregma';
            app.APEditField_17Label_2 = uilabel(app.Site3Tab);
            app.APEditField_17Label_2.Text = 'AP';
            app.APEditField_13 = uieditfield(app.Site3Tab, 'numeric');
            app.APEditField_13.ValueDisplayFormat = '%1.4f';
            app.APEditField_13.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.MLEditField_17Label_2 = uilabel(app.Site3Tab);
            app.MLEditField_17Label_2.Text = 'ML';
            app.MLEditField_13 = uieditfield(app.Site3Tab, 'numeric');
            app.MLEditField_13.ValueDisplayFormat = '%1.4f';
            app.MLEditField_13.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.DVEditField_17Label_2 = uilabel(app.Site3Tab);
            app.DVEditField_17Label_2.Text = 'DV';
            app.DVEditField_13 = uieditfield(app.Site3Tab, 'numeric');
            app.DVEditField_13.ValueDisplayFormat = '%1.4f';
            app.DVEditField_13.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.APEditField_18Label_2 = uilabel(app.Site3Tab);
            app.APEditField_18Label_2.Text = 'AP';
            app.APEditField_14 = uieditfield(app.Site3Tab, 'numeric');
            app.APEditField_14.ValueDisplayFormat = '%1.4f';
            app.APEditField_14.Editable = 'off';
            app.MLEditField_18Label_2 = uilabel(app.Site3Tab);
            app.MLEditField_18Label_2.Text = 'ML';
            app.MLEditField_14 = uieditfield(app.Site3Tab, 'numeric');
            app.MLEditField_14.ValueDisplayFormat = '%1.4f';
            app.MLEditField_14.Editable = 'off';
            app.DVEditField_18Label_2 = uilabel(app.Site3Tab);
            app.DVEditField_18Label_2.Text = 'DV';
            app.DVEditField_14 = uieditfield(app.Site3Tab, 'numeric');
            app.DVEditField_14.ValueDisplayFormat = '%1.4f';
            app.DVEditField_14.Editable = 'off';
            app.Site4Tab = uitab(app.TabGroup);
            app.Site4Tab.Title = 'Site 4';
            app.FiberCheckBox_7 = uicheckbox(app.Site4Tab);
            app.FiberCheckBox_7.Text = 'Fiber';
            app.LensCheckBox_7 = uicheckbox(app.Site4Tab);
            app.LensCheckBox_7.Text = 'Lens';
            app.AreaDropDown_7Label = uilabel(app.Site4Tab);
            app.AreaDropDown_7Label.Text = 'Area';
            app.AreaDropDown_4 = uidropdown(app.Site4Tab);
            app.AreaDropDown_4.Items = {'None', 'NBM', 'BLA', 'vHIPP', 'PFC', 'Add / Edit'};
            app.AreaDropDown_4.ValueChangedFcn = createCallbackFcn(app, @AreaDropDown_4ValueChanged, true);
            app.AreaDropDown_4.Value = 'None';
            app.MidlineDVEditField_7Label = uilabel(app.Site4Tab);
            app.MidlineDVEditField_7Label.Text = 'Midline DV';
            app.MidlineDVEditField_7 = uieditfield(app.Site4Tab, 'numeric');
            app.MidlineDVEditField_7.Limits = [0 10];
            app.MidlineDVEditField_7.ValueDisplayFormat = '%1.4f';
            app.MidlineDVEditField_7.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.VirusEditField_7Label = uilabel(app.Site4Tab);
            app.VirusEditField_7Label.Text = 'Virus';
            app.VirusEditField_7 = uieditfield(app.Site4Tab, 'text');
            app.VirusEditField_7.Value = 'name & conc';
            app.BilateralSwitch_4 = uiswitch(app.Site4Tab, 'slider');
            app.BilateralSwitch_4.Items = {'Bilateral', 'Unilateral'};
            app.BilateralSwitch_4.Value = 'Bilateral';
            app.LeftRightSwitch_4 = uiswitch(app.Site4Tab, 'slider');
            app.LeftRightSwitch_4.Items = {'Right', 'Left'};
            app.LeftRightSwitch_4.ValueChangedFcn = createCallbackFcn(app, @LeftRightSwitch_4ValueChanged, true);
            app.LeftRightSwitch_4.Value = 'Right';
            app.RelativeLabel_10 = uilabel(app.Site4Tab);
            app.RelativeLabel_10.Text = 'Relative';
            app.AbsoluteLabel_10 = uilabel(app.Site4Tab);
            app.AbsoluteLabel_10.Text = 'Absolute';
            app.LandmarkSwitch_4 = uiswitch(app.Site4Tab, 'slider');
            app.LandmarkSwitch_4.Items = {'Bregma', 'Lambda'};
            app.LandmarkSwitch_4.ValueChangedFcn = createCallbackFcn(app, @LandmarkSwitch_1ValueChanged, true);
            app.LandmarkSwitch_4.Value = 'Bregma';
            app.APEditField_15Label = uilabel(app.Site4Tab);
            app.APEditField_15Label.Text = 'AP';
            app.APEditField_15 = uieditfield(app.Site4Tab, 'numeric');
            app.APEditField_15.ValueDisplayFormat = '%1.4f';
            app.APEditField_15.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.MLEditField_15Label = uilabel(app.Site4Tab);
            app.MLEditField_15Label.Text = 'ML';
            app.MLEditField_15 = uieditfield(app.Site4Tab, 'numeric');
            app.MLEditField_15.ValueDisplayFormat = '%1.4f';
            app.MLEditField_15.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.DVEditField_15Label = uilabel(app.Site4Tab);
            app.DVEditField_15Label.Text = 'DV';
            app.DVEditField_15 = uieditfield(app.Site4Tab, 'numeric');
            app.DVEditField_15.ValueDisplayFormat = '%1.4f';
            app.DVEditField_15.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.APEditField_16Label = uilabel(app.Site4Tab);
            app.APEditField_16Label.Text = 'AP';
            app.APEditField_16 = uieditfield(app.Site4Tab, 'numeric');
            app.APEditField_16.ValueDisplayFormat = '%1.4f';
            app.APEditField_16.Editable = 'off';
            app.MLEditField_16Label = uilabel(app.Site4Tab);
            app.MLEditField_16Label.Text = 'ML';
            app.MLEditField_16 = uieditfield(app.Site4Tab, 'numeric');
            app.MLEditField_16.ValueDisplayFormat = '%1.4f';
            app.MLEditField_16.Editable = 'off';
            app.DVEditField_16Label = uilabel(app.Site4Tab);
            app.DVEditField_16Label.Text = 'DV';
            app.DVEditField_16 = uieditfield(app.Site4Tab, 'numeric');
            app.DVEditField_16.ValueDisplayFormat = '%1.4f';
            app.DVEditField_16.Editable = 'off';
            app.SaveLogButton = uibutton(app.RolemageLaboratoryUIFigure, 'push');
            app.SaveLogButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLogButtonPushed, true);
            app.SaveLogButton.Text = 'Save Log';
            app.SexDropDown = uidropdown(app.RolemageLaboratoryUIFigure);
            app.SexDropDown.Items = {'F', 'M'};
            app.SexDropDown.Value = 'F';
            app.DOBEditFieldLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.DOBEditFieldLabel.Text = 'DOB';
            app.DOBEditField = uieditfield(app.RolemageLaboratoryUIFigure, 'text');
            app.DOBEditField.Editable = 'off';
            app.DOBEditField.Value = 'MM/DD/YY';
            app.gEditFieldLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.gEditFieldLabel.Text = 'g';
            app.WeightEditField = uieditfield(app.RolemageLaboratoryUIFigure, 'numeric');
            app.WeightEditField.Limits = [0 50];
            app.WeightEditField.ValueDisplayFormat = '%2.1f';
            app.WeightEditField.ValueChangedFcn = createCallbackFcn(app, @WeightEditFieldValueChanged, true);
            app.WeightEditField.Value = 20;
            app.AddNotesButton = uibutton(app.RolemageLaboratoryUIFigure, 'push');
            app.AddNotesButton.ButtonPushedFcn = createCallbackFcn(app, @AddNotesButtonPushed, true);
            app.AddNotesButton.Text = 'Add Notes';
            app.CalButton = uibutton(app.RolemageLaboratoryUIFigure, 'push');
            app.CalButton.ButtonPushedFcn = createCallbackFcn(app, @CalButtonPushed, true);
            app.CalButton.Text = 'Cal';
            app.LookupButton = uibutton(app.RolemageLaboratoryUIFigure, 'push');
            app.LookupButton.ButtonPushedFcn = createCallbackFcn(app, @LookupButtonPushed, true);
            app.LookupButton.Text = 'Lookup';
            app.KetorolacLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.KetorolacLabel.Text = 'Ketorolac';
            app.KetorolacEditField = uieditfield(app.RolemageLaboratoryUIFigure, 'numeric');
            app.KetorolacEditField.Limits = [0 1];
            app.KetorolacEditField.ValueDisplayFormat = '%1.3f';
            app.KetorolacEditField.Editable = 'off';
            app.KetorolacEditField.Value = 0.1;
            app.mL01mL20gLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.mL01mL20gLabel.Text = 'mL (0.1 mL/20 g)';
            app.APEditField_2Label = uilabel(app.RolemageLaboratoryUIFigure);
            app.APEditField_2Label.Text = 'AP';
            app.APEditField_2 = uieditfield(app.RolemageLaboratoryUIFigure, 'numeric');
            app.APEditField_2.Limits = [0 10];
            app.APEditField_2.ValueDisplayFormat = '%1.4f';
            app.APEditField_2.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.CreatePDFButton = uibutton(app.RolemageLaboratoryUIFigure, 'push');
            app.CreatePDFButton.Text = 'Create PDF';
        end
    end

    methods (Access = public)

        % Construct app
        function app = surgerylog

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RolemageLaboratoryUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RolemageLaboratoryUIFigure)
        end
    end
end